classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadEditFieldLabel            matlab.ui.control.Label
        LoadEditField                 matlab.ui.control.NumericEditField
        SolidheightEditFieldLabel     matlab.ui.control.Label
        SolidheightEditField          matlab.ui.control.NumericEditField
        freelengthEditFieldLabel      matlab.ui.control.Label
        freelengthEditField           matlab.ui.control.NumericEditField
        MaxcompressionEditFieldLabel  matlab.ui.control.Label
        MaxcompressionEditField       matlab.ui.control.NumericEditField
        CalculateButton               matlab.ui.control.Button
        DEditFieldLabel               matlab.ui.control.Label
        DEditField                    matlab.ui.control.NumericEditField
        NaEditFieldLabel              matlab.ui.control.Label
        NaEditField                   matlab.ui.control.NumericEditField
        LsEditFieldLabel              matlab.ui.control.Label
        LsEditField                   matlab.ui.control.NumericEditField
        LoEditFieldLabel              matlab.ui.control.Label
        LoEditField                   matlab.ui.control.NumericEditField
        dEditFieldLabel               matlab.ui.control.Label
        dEditField                    matlab.ui.control.NumericEditField
        fomEditFieldLabel             matlab.ui.control.Label
        fomEditField                  matlab.ui.control.NumericEditField
        DesignofMachineElementsSpringDesignLabel  matlab.ui.control.Label
        AakashME16B001AdityaME16B002Label  matlab.ui.control.Label
        MVP1Label                     matlab.ui.control.Label
    end

%     properties (Access = public)
%         fmax1
%         freelength1
%         solidheight1
%         maxcompression1
%     end
   
%     methods (Access = private)
%         function appDesignerFunction(app)
%             [app.D, app.d, app.Na, app.ls, app.lo, app.fom] = myFunction(app.fmax1,app.freelength1, app.solidheight1,app.maxcompression1);
%         end
%         
%     end

    methods (Access = private)

        % Button pushed function: CalculateButton
        function calculate(app, event)
     [D, d, Na, ls, lo, fom] = myFunction(app.freelengthEditField.Value,app.MaxcompressionEditField.Value, app.freelengthEditField.Value,app.SolidheightEditField.Value);
%       [D, d, Na, ls, lo, fom] = myFunction(Fmax, ymax, freelength, solidlength);

      app.DEditField.Value = D;
      app.dEditField.Value = d;
      app.NaEditField.Value = Na;
      app.LsEditField.Value = ls;
      app.LoEditField.Value = lo;
      app.fomEditField.Value = fom;
        end

        % Value changed function: DEditField
        function capitalD(app, event)
            
        end

        % Value changed function: fomEditField
        function fomoutput(app, event)
           
        end

        % Value changed function: freelengthEditField
        function freelength(app, event)
%             app.freelength1 = app.freelengthEditField.Value;
        end

        % Value changed function: LoadEditField
        function load(app, event)
%             app.fmax1 = app.LoadEditField.Value;
        end

        % Value changed function: LoEditField
        function looutput(app, event)
            
        end

        % Value changed function: LsEditField
        function lsoutput(app, event)
            
        end

        % Value changed function: MaxcompressionEditField
        function maxcompression(app, event)
%             app.maxcompression1 = app.MaxcompressionEditField.Value;            
        end

        % Value changed function: NaEditField
        function naoutput(app, event)
            
        end

        % Value changed function: dEditField
        function smalld(app, event)
            
        end

        % Value changed function: SolidheightEditField
        function solidheight(app, event)
%             app.solidheight1 = app.SolidheightEditField.Value;           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadEditFieldLabel = uilabel(app.UIFigure);
            app.LoadEditFieldLabel.Text = 'Load';
            app.LoadEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadEditField.ValueChangedFcn = createCallbackFcn(app, @load, true);
            app.SolidheightEditFieldLabel = uilabel(app.UIFigure);
            app.SolidheightEditFieldLabel.Text = 'Solid height';
            app.SolidheightEditField = uieditfield(app.UIFigure, 'numeric');
            app.SolidheightEditField.ValueChangedFcn = createCallbackFcn(app, @solidheight, true);
            app.freelengthEditFieldLabel = uilabel(app.UIFigure);
            app.freelengthEditFieldLabel.Text = 'free length';
            app.freelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.freelengthEditField.ValueChangedFcn = createCallbackFcn(app, @freelength, true);
            app.MaxcompressionEditFieldLabel = uilabel(app.UIFigure);
            app.MaxcompressionEditFieldLabel.Text = 'Max compression';
            app.MaxcompressionEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxcompressionEditField.ValueChangedFcn = createCallbackFcn(app, @maxcompression, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculate, true);
            app.CalculateButton.Text = 'Calculate';
            app.DEditFieldLabel = uilabel(app.UIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEditField.ValueChangedFcn = createCallbackFcn(app, @capitalD, true);
            app.NaEditFieldLabel = uilabel(app.UIFigure);
            app.NaEditFieldLabel.Text = 'Na';
            app.NaEditField = uieditfield(app.UIFigure, 'numeric');
            app.NaEditField.ValueChangedFcn = createCallbackFcn(app, @naoutput, true);
            app.LsEditFieldLabel = uilabel(app.UIFigure);
            app.LsEditFieldLabel.Text = 'Ls';
            app.LsEditField = uieditfield(app.UIFigure, 'numeric');
            app.LsEditField.ValueChangedFcn = createCallbackFcn(app, @lsoutput, true);
            app.LoEditFieldLabel = uilabel(app.UIFigure);
            app.LoEditFieldLabel.Text = 'Lo';
            app.LoEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoEditField.ValueChangedFcn = createCallbackFcn(app, @looutput, true);
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @smalld, true);
            app.fomEditFieldLabel = uilabel(app.UIFigure);
            app.fomEditFieldLabel.Text = 'fom';
            app.fomEditField = uieditfield(app.UIFigure, 'numeric');
            app.fomEditField.ValueChangedFcn = createCallbackFcn(app, @fomoutput, true);
            app.DesignofMachineElementsSpringDesignLabel = uilabel(app.UIFigure);
            app.DesignofMachineElementsSpringDesignLabel.Text = 'Design of Machine Elements - Spring Design ';
            app.AakashME16B001AdityaME16B002Label = uilabel(app.UIFigure);
            app.AakashME16B001AdityaME16B002Label.Text = 'Aakash (ME16B001) & Aditya (ME16B002)';
            app.MVP1Label = uilabel(app.UIFigure);
            app.MVP1Label.Text = 'MVP1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end